classdef Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TurbuladorcontrollerUIFigure  matlab.ui.Figure
        Label                         matlab.ui.control.Label
        SAVEButton                    matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        VcoldSlider                   matlab.ui.control.Slider
        VcoldSliderLabel              matlab.ui.control.Label
        VhotSlider                    matlab.ui.control.Slider
        VhotSliderLabel               matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
        VariacSlider                  matlab.ui.control.Slider
        VariacSliderLabel             matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        ReadyLamp                     matlab.ui.control.Lamp
        FunTemperatureGauge           matlab.ui.control.SemicircularGauge
        FunTemperatureGaugeLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Objetos por definir
        arduino = []; % Description
        Nport = [];
        table = [];
        count = 1;

        % Termocuplas
        T_1 = [];
        T_2 = [];
        T_3 = [];
        T_4 = [];
        

        % HDR1
        T_out = 0;
        Hume = 0; %humedad en "%"

        % Sensor Temp ventilador
        T_em = 0;
        I_amp = 0;

        % CONTROL
        on_off = 0;
        ValH = 100;
        ValC = 100;
        Pot = 100;
        Sendfunc = '0000000000'; % Description
    end
    
    methods (Access = private)
        
        function tab = read_ard(app)
            Tx = num2str(app.arduino.readline);
            D = [num2str(hour(datetime)) ':' num2str(minute(datetime)) ':' num2str(round(second(datetime)),3)];
            hora = floor(hour(datetime));
            minu = floor(minute(datetime));
            seg = floor(second(datetime));
            pot = floor(app.VariacSlider.Value);
            valh = floor(app.VhotSlider.Value);
            valc = floor(app.VcoldSlider.Value);


            tab = [hora minu seg pot app.I_amp valh valc app.Hume];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            delete(instrfind);
            p = instrhwinfo('serial');
            app.DropDown.Items = p.AvailableSerialPorts;
            app.ConnectButton.Enable = 1;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if exist('app.DropDown.Value')         
                app.arduino = serialport(app.DropDown.Value,57600,"Timeout",5);
                app.Switch.Enable = 1;
                app.ConnectButton.Enable = 0;
                app.DropDown.Enable = 0;
                app.RefreshButton.Enable = 0;
                app.Label.Text = ['Arduino connected in port: ' tring(Nport)];
            else
                app.Label.Text = 'No Arduino selected';
            end

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            value = app.Switch.Value
            while strfind(value,'on')
                app.VariacSlider.Enable = 1;
                app.VhotSlider.Enable = 1;
                app.VcoldSlider.Enable = 1;
                app.SAVEButton.Enable = 0;
                app.Sendfunc = ['1' num2str(floor(app.Pot)) num2str(floor(app.ValH)) num2str(floor(app.ValC))];
                writeline(app.arduino,app.Sendfunc);
                pause(0.2)
                app.Label.Text = app.Sendfunc;
                app.ReadyLamp.Color = [0 1 0];
                app.FunTemperatureGauge.Value = 20;


                    app.T_1 = cat(1,app.T_1,app.VhotSlider.Value);
                    app.T_2 = cat(1,app.T_2,app.VcoldSlider.Value);
                    app.T_3 = cat(1,app.T_3,app.VariacSlider.Value);
                    tab = read_ard(app);
                    app.table = cat(1,tab,app.table);
                    plot(app.UIAxes,...
                        1:length(app.T_1),app.T_1,...
                        1:length(app.T_2),app.T_2,...
                        1:length(app.T_3),app.T_3/3,...
                        'LineWidth',2);
                    legend(app.UIAxes,['Vel hot'],['Vel cold'],['Pot Variac']);
                    app.UITable.Data = app.table;
                    pause(.01)
                    app.count = app.count+1;
            
                app.VariacSlider.Enable = 0;
                app.VhotSlider.Enable = 0;
                app.VcoldSlider.Enable = 0;
                app.SAVEButton.Enable = 1;
                value = app.Switch.Value;
            end
                writeline(app.arduino,'1100110110');
                pause(1)
                writeline(app.arduino,'0100100100');
                app.Label.Text = num2str(value);
                app.ReadyLamp.Color = [1 0 0];
                app.FunTemperatureGauge.Value = 0;
   
        end

        % Value changed function: VhotSlider
        function VhotSliderValueChanged(app, event)
            app.ValH = floor(app.VhotSlider.Value+100);
            
        end

        % Value changed function: VcoldSlider
        function VcoldSliderValueChanged(app, event)
            app.ValC = floor(app.VcoldSlider.Value+100);
            
        end

        % Value changed function: VariacSlider
        function VariacSliderValueChanged(app, event)
            app.Pot = floor(app.VariacSlider.Value+100);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.Nport = app.DropDown.Value;
            display(app.Nport);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TurbuladorcontrollerUIFigure = uifigure('Visible', 'off');
            app.TurbuladorcontrollerUIFigure.Name = 'Turbulador controller';
            app.UIAxes = uiaxes(app.TurbuladorcontrollerUIFigure);
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Temperature [°C]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 100];
            app.FunTemperatureGaugeLabel = uilabel(app.TurbuladorcontrollerUIFigure);
            app.FunTemperatureGaugeLabel.Text = 'Fun Temperature';
            app.FunTemperatureGauge = uigauge(app.TurbuladorcontrollerUIFigure, 'semicircular');
            app.FunTemperatureGauge.Tag = 'T_fun';
            app.ReadyLamp = uilamp(app.TurbuladorcontrollerUIFigure);
            app.Switch = uiswitch(app.TurbuladorcontrollerUIFigure, 'toggle');
            app.Switch.Items = {'off', 'on'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'off';
            app.VariacSliderLabel = uilabel(app.TurbuladorcontrollerUIFigure);
            app.VariacSliderLabel.Enable = 'off';
            app.VariacSliderLabel.Text = 'Variac';
            app.VariacSlider = uislider(app.TurbuladorcontrollerUIFigure);
            app.VariacSlider.Limits = [0 256];
            app.VariacSlider.ValueChangedFcn = createCallbackFcn(app, @VariacSliderValueChanged, true);
            app.VariacSlider.Enable = 'off';
            app.DropDown = uidropdown(app.TurbuladorcontrollerUIFigure);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.RefreshButton = uibutton(app.TurbuladorcontrollerUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.TurbuladorcontrollerUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.VhotSliderLabel = uilabel(app.TurbuladorcontrollerUIFigure);
            app.VhotSliderLabel.Enable = 'off';
            app.VhotSliderLabel.Text = 'V hot';
            app.VhotSlider = uislider(app.TurbuladorcontrollerUIFigure);
            app.VhotSlider.Limits = [0 40];
            app.VhotSlider.ValueChangedFcn = createCallbackFcn(app, @VhotSliderValueChanged, true);
            app.VhotSlider.Enable = 'off';
            app.VcoldSliderLabel = uilabel(app.TurbuladorcontrollerUIFigure);
            app.VcoldSliderLabel.Enable = 'off';
            app.VcoldSliderLabel.Text = 'V cold';
            app.VcoldSlider = uislider(app.TurbuladorcontrollerUIFigure);
            app.VcoldSlider.Limits = [0 40];
            app.VcoldSlider.ValueChangedFcn = createCallbackFcn(app, @VcoldSliderValueChanged, true);
            app.VcoldSlider.Enable = 'off';
            app.UITable = uitable(app.TurbuladorcontrollerUIFigure);
            app.UITable.ColumnName = {'H'; 'min'; 'sec'; 'P (variac)'; 'I (A)'; 'V hot'; 'V cold'; 'Hume (%)'; 'T (°C) out'; 'T1 (°C)'; 'T2 (°C)'; 'T3 (°C)'; 'T4 (°C)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SAVEButton = uibutton(app.TurbuladorcontrollerUIFigure, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.Label = uilabel(app.TurbuladorcontrollerUIFigure);
            app.Label.Text = {''; ''};
            % Show the figure after all components are created
            app.TurbuladorcontrollerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TurbuladorcontrollerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TurbuladorcontrollerUIFigure)
        end
    end
end